classdef ParticleDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParticleDetectionApplicationUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ParticleDetectionTab            matlab.ui.container.Tab
        ImageSliderLabel                matlab.ui.control.Label
        ImageSlider                     matlab.ui.control.Slider
        imxFrameSwitchLabel             matlab.ui.control.Label
        imxFrameSwitch                  matlab.ui.control.Switch
        RefreshImageButton              matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        SelectImagesButton              matlab.ui.control.Button
        MarkerOptionsPanel              matlab.ui.container.Panel
        RadiusSpinnerLabel              matlab.ui.control.Label
        RadiusSpinner                   matlab.ui.control.Spinner
        MarkerDropDownLabel             matlab.ui.control.Label
        MarkerDropDown                  matlab.ui.control.DropDown
        ImageIDdigitsEditFieldLabel     matlab.ui.control.Label
        ImageIDdigitsEditField          matlab.ui.control.NumericEditField
        NumberofImagesEditFieldLabel    matlab.ui.control.Label
        NumberofImagesEditField         matlab.ui.control.NumericEditField
        StatusTextAreaLabel             matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        ParticleDetectionSettingsPanel  matlab.ui.container.Panel
        MinimumBrightnessSpinnerLabel   matlab.ui.control.Label
        MinimumBrightnessSpinner        matlab.ui.control.Spinner
        ParticleWindowSizeSpinnerLabel  matlab.ui.control.Label
        ParticleWindowSizeSpinner       matlab.ui.control.Spinner
        ImageProcessingPanel            matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RawImageButton                  matlab.ui.control.RadioButton
        InvertedImageButton             matlab.ui.control.RadioButton
        GenerateAndApplyMaskButton      matlab.ui.control.Button
        ApplyCustomMaskButton           matlab.ui.control.Button
        FilterByThresholdCheckBox       matlab.ui.control.CheckBox
        HighpassFilteringCheckBox       matlab.ui.control.CheckBox
        ChooseIntensityThresholdButton  matlab.ui.control.Button
        ColourMapMaxEditFieldLabel      matlab.ui.control.Label
        ColourMapMaxEditField           matlab.ui.control.NumericEditField
        Panel_4                         matlab.ui.container.Panel
        ProcessSingleImageButton        matlab.ui.control.Button
        ShowParticlesCheckBox           matlab.ui.control.CheckBox
        ProcessAllImagesButton          matlab.ui.control.Button
        RunSingleImageStatisticsButton  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        SingleImageStatisticsTab        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        ParticleSizeTab                 matlab.ui.container.Tab
        UIAxesPartSize                  matlab.ui.control.UIAxes
        AverageParticleIntensityTab     matlab.ui.container.Tab
        UIAxesPartInt                   matlab.ui.control.UIAxes
        PeakParticleIntensityTab        matlab.ui.container.Tab
        UIAxesPartInt_2                 matlab.ui.control.UIAxes
        UIAxesProcImg                   matlab.ui.control.UIAxes
        GroupStatisticsTab              matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        NumberofParticlesPerImageTab    matlab.ui.container.Tab
        UIAxesNumPart                   matlab.ui.control.UIAxes
        CumulativeParticleSizeTab       matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        AverageParticleIntensityTab_2   matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        PeakParticleIntensityTab_2      matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        CombinedStatisticsTab           matlab.ui.container.Tab
        UIAxes4_2                       matlab.ui.control.UIAxes
        LoadSaveTab                     matlab.ui.container.Tab
        SaveDataPanel                   matlab.ui.container.Panel
        SaveGraphDataButton             matlab.ui.control.Button
        GenerateReportButton            matlab.ui.control.Button
        LoadSavedDataButton             matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        SaveIndividualStatisticsPanel   matlab.ui.container.Panel
        SingleImageStatisticsPanel      matlab.ui.container.Panel
        ParticleSizeHistogramCheckBox   matlab.ui.control.CheckBox
        OutputFilenameEditField_3Label  matlab.ui.control.Label
        OutputFilenameEditField_ParticleSize  matlab.ui.control.EditField
        AverageParticleIntensityCheckBox  matlab.ui.control.CheckBox
        OutputFilenameEditField_4Label  matlab.ui.control.Label
        OutputFilenameEditField_AveParticleIntensity  matlab.ui.control.EditField
        PeakParticleIntensityCheckBox   matlab.ui.control.CheckBox
        OutputFilenameEditFieldLabel    matlab.ui.control.Label
        OutputFilenameEditField         matlab.ui.control.EditField
        GroupStatisticsPanel            matlab.ui.container.Panel
        NumberofParticlesCheckBox       matlab.ui.control.CheckBox
        CumulativeParticleSizeCheckBox  matlab.ui.control.CheckBox
        OutputFilenameEditField_5Label  matlab.ui.control.Label
        OutputFilenameEditField_NumParticles  matlab.ui.control.EditField
        OutputFilenameEditField_6Label  matlab.ui.control.Label
        OutputFilenameEditField_CumPartSize  matlab.ui.control.EditField
        AverageParticleIntensityCheckBox_2  matlab.ui.control.CheckBox
        PeakParticleIntensityCheckBox_2  matlab.ui.control.CheckBox
        CombinedStatisticsCheckBox      matlab.ui.control.CheckBox
        OutputFilenameEditField_2Label  matlab.ui.control.Label
        OutputFilenameEditField_2       matlab.ui.control.EditField
        OutputFilenameEditField_3Label_2  matlab.ui.control.Label
        OutputFilenameEditField_3       matlab.ui.control.EditField
        OutputFilenameEditField_4Label_2  matlab.ui.control.Label
        OutputFilenameEditField_4       matlab.ui.control.EditField
        ChooseOutputFolderButton_IndStat  matlab.ui.control.Button
        SaveStatisticsButton            matlab.ui.control.Button
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        figButton                       matlab.ui.control.RadioButton
        pngButton                       matlab.ui.control.RadioButton
        SaveImagesPanel                 matlab.ui.container.Panel
        SaveImagesButton                matlab.ui.control.Button
        SaveGifButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        imArray = {};
        imRaw = {};
        maskOn = false;
        Mask;
        imName;
        imPath;
        sPlotArray = {};
        cellArrayText = {};
        outputFilepath_GroupStat;
        outputFilepath_IndStat;
        axesCMap;
        
        PPP;
        
        Isimx = false;
        invertColour = false;
        filterThresh = false;
        filterGradient = false;
        settings = [];
        marker = 'o';
        radius = 5;
        
        particleIntensityOutput;
        peakIntensityOutput;
        averageParticleSizeOutput;
        cumulativeParticleSize;
        cumulativeParticleIntensity;
        cumulativePeakIntensity;
        combinedStats;
        
        numParticlesOutput;
        outputFilepath_Report;
    end
    
    methods (Access = private)
        
        % Displays imx image frames on the specified axes object
        function im = imagescimx( app, Axis, Frame )
            Components = Frame.Components;
            Planes = Components{1}.Planes;
            plane = Planes{1};
            scales = Frame.Scales;
            D = createPlane( plane, scales );
        
            %Display image data
            axis(Axis, "equal");
            im = D.I';
            im = applyFilter(app,im);
            imagesc( Axis, im);
            set(Axis, 'YDir', 'reverse');
        end


        % Finds all particles on the currently selected image
        % and displays them over the image
        function cnt = findParticles(app)
            if app.Isimx
                Frame = app.imArray{1,app.ImageSlider.Value}.Frames{str2double(app.imxFrameSwitch.Value)};
                Components = Frame.Components;
                Planes = Components{1}.Planes;
                plane = Planes{1};
                scales = Frame.Scales;
                D = createPlane( plane, scales );
                im = D.I';
                im = applyFilter(app,im);
                [cnt, firstIm, p2, ~] = JH_ParticleFinder(app.settings, im);
                
                [rows, columns] = size(im);
                app.PPP = length(cnt) / (rows*columns);
            else
                im = app.imArray{1,app.ImageSlider.Value};
                im = applyFilter(app,im);
                [cnt, firstIm, p2, ~] = JH_ParticleFinder(app.settings, im);
                
                [rows, columns] = size(im);
                app.PPP = length(cnt) / (rows*columns);
            end
            rad = app.radius;
            % display image
            imagesc(app.UIAxes, firstIm);
            % display plot
            hold(app.UIAxes, 'on');
            scatter(app.UIAxes,p2(:,1),p2(:,2),pi*rad*rad,...
            'MarkerEdgeColor', 'red', 'Marker', app.marker);
            hold(app.UIAxes, 'off');
            % save plot
            index = app.ImageSlider.Value;
            if app.Isimx
                app.sPlotArray{str2double(app.imxFrameSwitch.Value), index} = p2;
            else
                app.sPlotArray{index} = p2;
            end
        end
        
        % displays the currently selected image and its particle data if it
        % has any and is selected by the user
        function displayImage(app, value, Axis)
            im = app.imArray{1,value};
            cla(Axis);
            try
                if app.Isimx
                    axis(Axis, "equal");
                    app.imagescimx(Axis, im.Frames{str2double(app.imxFrameSwitch.Value)});
                    if app.ShowParticlesCheckBox.Value
                        rad = app.radius;
                        p2 = app.sPlotArray{str2double(app.imxFrameSwitch.Value), value};
                        hold(Axis, 'on');
                        scatter(app.UIAxes,p2(:,1),p2(:,2),pi*rad*rad,...
                        'MarkerEdgeColor', 'red', 'Marker', app.marker);
                        hold(Axis, 'off');
                    end
                else
                    axis(Axis, "equal");
                    im = applyFilter(app,im);
                    imagesc(Axis, im);
                    
                    if app.ShowParticlesCheckBox.Value
                        rad = app.radius;
                        p2 = app.sPlotArray{value};
                        hold(Axis, 'on');
                        scatter(app.UIAxes,p2(:,1),p2(:,2),pi*rad*rad,...
                        'MarkerEdgeColor', 'red', 'Marker', app.marker);
                        hold(Axis, 'off');
                    end
                end
            catch ME
                
            end
        end
        
        % Displays an image intensity histogram with a red line at the
        % threshold value
        function imageIntensity(app)
            img = app.imArray{app.ImageSlider.Value};
            if app.Isimx
                Frame = img.Frames{str2double(app.imxFrameSwitch.Value)};
                Components = Frame.Components;
                Planes = Components{1}.Planes;
                plane = Planes{1};
                scales = Frame.Scales;
                D = createPlane( plane, scales );
                img = D.I';
            end
            img = applyFilter(app,img);
            if max(max(img)) > 255 && max(max(img)) < 4096
                img = uint16(img);
                R = [0:4095];
                G = [0:4095];
                B = [0:4095];
                map = [R',G',B']/4095;
                imhist(img, map);
                [counts, bins] = imhist(img, map);
            elseif max(max(img)) > 4096
                img = uint16(img);
                imhist(img);
                [counts, bins] = imhist(img);
            else
                imhist(img);
                [counts, bins] = imhist(img);
            end
            if ~iscolumn(bins)
                bins = bins';
            end
            if app.settings(5) == 0
                ave = sum(counts.*bins)/sum(counts);
                ave = 1.5*ave;
                hold on;
                xline(ave, 'r');
                hold off;
            else
                hold on;
                xline(app.settings(5), 'r');
                hold off;
            end
            if app.filterThresh || app.filterGradient
                temp = sort(counts);
                ylim([0,temp(end-1)]);
            end
        end
        
        % Displays an average particle size histogram in the single image
        % statistics tab
        function averageParticleSize(app)
            if app.Isimx
                p2 = app.sPlotArray{str2double(app.imxFrameSwitch.Value), app.ImageSlider.Value};
            else
                p2 = app.sPlotArray{app.ImageSlider.Value};
            end
            pSize = p2(:,5);
            app.averageParticleSizeOutput = pSize;
            histogram(app.UIAxesPartSize, pSize);
        end
        
        % Displayes an average particle intensity as well as peak particle
        % intensity histogram in the single image statistics tab
        function particleIntensity(app)
            if app.Isimx
                p2 = app.sPlotArray{str2double(app.imxFrameSwitch.Value), app.ImageSlider.Value};
            else
                p2 = app.sPlotArray{app.ImageSlider.Value};
            end
            % Average
            totalArea = app.averageParticleSizeOutput;
            avgIntensity = p2(:,3)./totalArea;
            
            meanIntensity = mean(avgIntensity);
            meanLabel = sprintf('mean = %.4f', meanIntensity);
            
            maxIntensity = max(avgIntensity);
            maxLabel = sprintf('max = %.4f', maxIntensity);
            
            app.particleIntensityOutput = avgIntensity;
            histogram(app.UIAxesPartInt, avgIntensity);
            xline(app.UIAxesPartInt, meanIntensity, 'r:', meanLabel);
            xline(app.UIAxesPartInt, maxIntensity, 'r:', maxLabel);
            
            % Peak
            peakIntensity = p2(:,6);
            
            meanIntensity = mean(peakIntensity);
            meanLabel = sprintf('mean = %.4f', meanIntensity);
            
            maxIntensity = max(peakIntensity);
            maxLabel = sprintf('max = %.4f', maxIntensity);
            
            app.peakIntensityOutput = peakIntensity;
            histogram(app.UIAxesPartInt_2, peakIntensity);
            xline(app.UIAxesPartInt_2, meanIntensity, 'r:', meanLabel);
            xline(app.UIAxesPartInt_2, maxIntensity, 'r:', maxLabel);
        end
        
        % Displays the axes in the group statistics tab
        function displayGroupStatistics(app)
            %% Number of particles
            plot(app.UIAxesNumPart, app.numParticlesOutput);
            if app.Isimx
                app.UIAxesNumPart.XLim = [0.5, app.ImageSlider.Limits(2)*2 + 0.5];
            else
                app.UIAxesNumPart.XLim = [0.5, app.ImageSlider.Limits(2) + 0.5];
            end
            %% Particle Size
            histogram(app.UIAxes2, app.cumulativeParticleSize);
            %% Average Intensity
            histogram(app.UIAxes3, app.cumulativeParticleIntensity);
            %% Peak Intensity
            histogram(app.UIAxes4, app.cumulativePeakIntensity);
            %% Combined Statistics
            for i = 1:4
                maxe(i) = max(app.combinedStats(:,i));
                plotcombinedStats(:,i) = app.combinedStats(:,i)/max(app.combinedStats(:,i));
            end
            plot(app.UIAxes4_2, plotcombinedStats);
            app.UIAxes4_2.XLim = [0.5,length(plotcombinedStats(:,1))+0.5];
            legend(app.UIAxes4_2, ['Number of Particles /',num2str(maxe(1))], ['Average Particle Size /',num2str(maxe(2))], ...
                ['Average Intensity /',num2str(maxe(3))], ['Average Peak Intensity /',num2str(maxe(4))]);
            xlabel(app.UIAxes4_2, 'Image');
            ylabel(app.UIAxes4_2, 'Value / Max');
        end
        
        % Applies a mask to all images loaded into the app
        function applyMask(app)
            for i = 1 : length(app.imArray)
                if app.Isimx
                    app.imArray{1,i}.Frames{1} = app.Mask.*app.imArray{1,i}.Frames{1};
                    app.imArray{1,i}.Frames{2} = app.Mask.*app.imArray{1,i}.Frames{2};
                else
                    app.imArray{1,i} = app.Mask.*app.imArray{1,i};
                end
            end
            app.maskOn = true;
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end
        
        % Inverts the image
        function invIm = invertImageColour(app, im)
            invIm = imcomplement(im);
            if app.maskOn
                invIm = app.Mask.*invIm;
            end
        end
        
        % Filters the image by a custom intensity threshold value
        function im = filterImThresh(app, im)
            thresh = app.settings(5);
            [rows, columns] = size(im);
            for j = 1:rows
                for k = 1:columns
                    if im(j,k) < thresh
                        im(j,k) = 0;
                    end
                end
            end
        end
        
        % Applies a highpass filter to the image as a mask
        function im = filterImHighpass(~, im)
            highpsize=15;
            h = fspecial('gaussian', highpsize, highpsize);
            him = (im-(imfilter(im,h,'replicate')));
            him = abs(him);
            thresh = max(him)*0.70;
            [rows, columns] = size(him);
            for j = 1:rows
                for k = 1:columns
                    if him(j,k) < thresh
                        him(j,k) = 0;
                    else
                        him(j,k) = 1;
                    end
                end
            end
            im = him.*im;
        end
        
        % Applies all selected filters to the image
        function im = applyFilter(app, im)
            if app.invertColour
                im = invertImageColour(app, im);
            end
            if app.filterThresh
                im = filterImThresh(app, im);
            end
            if app.filterGradient
                im = filterImHighpass(app, im);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.settings(1)=20; % Peak threshold (minimum brightness)
            app.settings(2)=3; % Particle Size
            app.settings(3)=0; % Noise Scale 
            app.settings(4)=0; % Noise Threshold
            app.settings(5)=0; % Intensity Threshold
            app.settings(6)=8;  %%% drt %%%
            app.settings(7)=3; 
            app.settings(8)=8; 
            app.settings(9)=0.75;
            app.settings(10)=3; 
            app.settings(11) = 0.1; %%% zdt %%%
            
            colormap(app.UIAxes, "gray");
            colormap(app.UIAxesProcImg, "gray");
        end

        % Button pushed function: SelectImagesButton
        function SelectImagesButtonPushed(app, event)
             % gets the file location
            [filename,filepath] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp';'*.oct';'*.tiff';'*.tif';'*.im7'}, 'Select File to Open');
            if (filename ~= 0) % file selected
                % cleans out old data
                app.imArray = {};
                app.sPlotArray = {};
                app.Isimx = false;
                fullname = [filepath, filename];
                app.imName = filename;
                app.imPath = filepath;
                numDig = app.ImageIDdigitsEditField.Value;
                dotPos = strfind(filename, '.');
                extension = extractAfter(filename, dotPos);
                % imx extension
                if isequal(extension,'im7')
                    app.Isimx = true;
                    app.imxFrameSwitch.Enable = true;
                    if numDig > 0
                        initialNum = str2double(extractBetween(filename,dotPos-numDig,dotPos-1));
                        for i = 1:app.NumberofImagesEditField.Value
                            postfix = string(initialNum-1+i);
                            while strlength(postfix) < numDig
                                postfix = '0' + postfix;
                            end
                            filename = replaceBetween(filename, dotPos-numDig, dotPos-1, postfix);
                            fullname = [filepath, filename];
                            imx = readimx(fullname);
                            app.imArray{1,i} = imx;
                            app.imRaw{1,i} = imx;
                        end
                        % display image
                        app.imagescimx(app.UIAxes, app.imArray{1,app.ImageSlider.Value}.Frames{1});
                        
                    else
                        imx = readimx(fullname);
                        app.imArray{1,1} = imx;
                        app.imRaw{1,1} = imx;
                        app.imagescimx(app.UIAxes, app.imArray{1,app.ImageSlider.Value}.Frames{1});
    
                    end
                %non imx extension
                else
                    if numDig > 0
                        initialNum = str2double(extractBetween(filename,dotPos-numDig,dotPos-1));
                        for i = 1:app.NumberofImagesEditField.Value
                            postfix = string(initialNum-1+i);
                            while strlength(postfix) < numDig
                                postfix = '0' + postfix;
                            end
                            filename = replaceBetween(filename, dotPos-numDig, dotPos-1, postfix);
                            fullname = [filepath, filename];
                            im = imread(fullname);
                            app.imArray{1,i} = im;
                            app.imRaw{1,i} = im;
                        end
                        % axis settings
                        axis(app.UIAxes, "equal");
                        % display image
                        imagesc(app.UIAxes, app.imArray{1,app.ImageSlider.Value});
                        
                    else
                        im = imread(fullname);
                        app.imArray{1,1} = im;
                        app.imRaw{1,1} = im;
                        axis(app.UIAxes, "equal");
                        imagesc(app.UIAxes, im);
                    end
                end
                app.ProcessSingleImageButton.Enable = true;
                app.ProcessAllImagesButton.Enable = true;
                app.ChooseIntensityThresholdButton.Enable = true;
            end
        end

        % Value changed function: ImageSlider
        function ImageSliderValueChanged(app, event)
            value = app.ImageSlider.Value;
            % sets slider to integer values only
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            % sets slider to position
            event.Source.Value = event.Source.MinorTicks(minIdx);
            value = event.Source.MinorTicks(minIdx);
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end

        % Value changed function: imxFrameSwitch
        function imxFrameSwitchValueChanged(app, event)
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end

        % Value changed function: NumberofImagesEditField
        function NumberofImagesEditFieldValueChanged(app, event)
            if app.NumberofImagesEditField.Value > 1
                value = app.NumberofImagesEditField.Value;
                app.ImageSlider.Limits = [1 value];
                if value < 25
                    labels = 1:value;
                    app.ImageSlider.MajorTicks = labels;
                    app.ImageSlider.MinorTicks = 1:value;
                    app.ImageSlider.MajorTickLabels = string(labels);
                elseif value >= 25 && value < 100
                    labels = [1, 5:5:value];
                    app.ImageSlider.MajorTicks = labels;
                    app.ImageSlider.MinorTicks = 1:value;
                    app.ImageSlider.MajorTickLabels = string(labels);
                elseif value >= 100
                    labels = [1, 10:10:value];
                    app.ImageSlider.MajorTicks = labels;
                    app.ImageSlider.MinorTicks = 1:value;
                    app.ImageSlider.MajorTickLabels = string(labels);
                end
            end
        end

        % Button pushed function: ProcessSingleImageButton
        function ProcessSingleImageButtonPushed(app, event)
%             try
%                 cnt = findParticles(app);
%                 app.cellArrayText{end+1} = sprintf('%s\n%s', 'Particles Detected: '+ string(length(cnt)) + '; Particles-Per-Pixel: ' + string(app.PPP));
%                 app.StatusTextArea.Value = flip(app.cellArrayText);
%                 app.RunSingleImageStatisticsButton.Enable = true;
%             catch ME
%                 
%             end
            cnt = findParticles(app);
            app.cellArrayText{end+1} = sprintf('%s\n%s', 'Particles Detected: '+ string(length(cnt)) + '; Particles-Per-Pixel: ' + string(app.PPP));
            app.StatusTextArea.Value = flip(app.cellArrayText);
            app.RunSingleImageStatisticsButton.Enable = true;
        end

        % Value changed function: ShowParticlesCheckBox
        function ShowParticlesCheckBoxValueChanged(app, event)
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end

        % Value changed function: MinimumBrightnessSpinner
        function MinimumBrightnessSpinnerValueChanged(app, event)
            value = app.MinimumBrightnessSpinner.Value;
            app.settings(1) = value;
        end

        % Value changed function: ParticleWindowSizeSpinner
        function ParticleWindowSizeSpinnerValueChanged(app, event)
            value = app.ParticleWindowSizeSpinner.Value;
            app.settings(2) = value;
        end

        % Button pushed function: ProcessAllImagesButton
        function ProcessAllImagesButtonPushed(app, event)
            set(app.ImageSlider, 'Value', 1);
            set(app.imxFrameSwitch, 'Value', '1');
            displayImage(app, 1, app.UIAxes);
            app.sPlotArray = {};
            pArray = {};
            if app.Isimx
                Frame = app.imArray{1,1}.Frames{str2double(app.imxFrameSwitch.Value)};
                Components = Frame.Components;
                Planes = Components{1}.Planes;
                plane = Planes{1};
                scales = Frame.Scales;
                D = createPlane( plane, scales );
                im = D.I';
                im = applyFilter(app,im);
            else
                im = app.imArray{1,1};
                im = applyFilter(app,im);
            end
            [p1, im, p2] = JH_ParticleFinder(app.settings, im);
            numParticles = zeros(1,app.ImageSlider.Limits(2));
            numParticles(1) = length(p2);
            
            pSize = p2(:,5);
            % Average Intensity
            totalArea = p2(:,5);
            avgIntensity = p2(:,3)./totalArea;
            % Peak Intensity
            peakIntensity = p2(:,6);
            
            app.cellArrayText{end+1} = sprintf('%s\n%s', 'Processing');
            app.StatusTextArea.Value = flip(app.cellArrayText);
            drawnow;
            
            if app.NumberofImagesEditField.Value > 1
                if app.Isimx %%% imx %%%
                    pArray = cell(2,app.ImageSlider.Limits(2));
                    for i = 1:app.ImageSlider.Limits(2)
                        
                        % Frame 1
                        if app.ImageSlider.Value ~= app.ImageSlider.Limits(2)
                            set(app.imxFrameSwitch, 'Value', '1');
                            set(app.ImageSlider, 'Value', i);
                            displayImage(app, i, app.UIAxes);
                        end
                        drawnow;
                        
                        Frame = app.imArray{1,i}.Frames{1};
                        Components = Frame.Components;
                        Planes = Components{1}.Planes;
                        plane = Planes{1};
                        scales = Frame.Scales;
                        D = createPlane( plane, scales );
                        im = D.I';
                        im = applyFilter(app,im);
                        
                        [p1, ~, p2] = JH_ParticleFinder(app.settings, im);
                        pArray{1,i} = p2;
                        numParticles(i*2-1) = length(p2);
                        
                        app.combinedStats(i*2-1,1) = numParticles(i*2-1);
                        app.combinedStats(i*2-1,2) = mean(p2(:,5));
                        
                        totalArea = p2(:,5);
                        app.combinedStats(i*2-1,3) = mean(p2(:,3)./totalArea);
                        app.combinedStats(i*2-1,4) = mean(p2(:,6));
                        if i ~= 1
                            pSize = [pSize; p2(:,5)];
                            % Average Intensity
                            totalArea = p2(:,5);
                            avgIntensity = [avgIntensity; p2(:,3)./totalArea];
                            % Peak Intensity
                            peakIntensity = [peakIntensity; p2(:,6)];
                        end
                        
                        % Frame 2
                        set(app.imxFrameSwitch, 'Value', '2');
                        displayImage(app, i, app.UIAxes);
                        drawnow;
                        
                        Frame = app.imArray{1,i}.Frames{2};
                        Components = Frame.Components;
                        Planes = Components{1}.Planes;
                        plane = Planes{1};
                        scales = Frame.Scales;
                        D = createPlane( plane, scales );
                        im = D.I';
                        im = applyFilter(app,im);
                        [p1, ~, p2] = JH_ParticleFinder(app.settings, im);
                        pArray{2,i} = p2;
                        numParticles(i*2) = length(p2);
                        
                        app.combinedStats(i*2,1) = numParticles(i*2);
                        app.combinedStats(i*2,2) = mean(p2(:,5));
                        
                        pSize = [pSize; p2(:,5)];
                        % Average Intensity
                        totalArea = p2(:,5);
                        avgIntensity = [avgIntensity; p2(:,3)./totalArea];
                        app.combinedStats(i*2,3) = mean(p2(:,3)./totalArea);
                        app.combinedStats(i*2,4) = mean(p2(:,6));
                        % Peak Intensity
                        peakIntensity = [peakIntensity; p2(:,6)];
                        
                        temp = app.cellArrayText{end};
                        if strcmp(temp(1:7), 'Percent')
                            app.cellArrayText{end} = sprintf('%s\n%s','Percent Completed: ' + string(round((i/app.ImageSlider.Limits(2)*100))) + '%');
                        else
                            app.cellArrayText{end+1} = sprintf('%s\n%s','Percent Completed: ' + string(round((i/app.ImageSlider.Limits(2)*100))) + '%');
                        end
                        app.StatusTextArea.Value = flip(app.cellArrayText);
                        drawnow;
                    end
                    
                    app.numParticlesOutput = numParticles;
                    
                else %%% not imx %%%
                    pArray = cell(app.ImageSlider.Limits(2));
                    for i = 1:app.ImageSlider.Limits(2)
                        
                        im = app.imArray{1,i};
                        im = applyFilter(app,im);
                        [p1, ~, p2] = JH_ParticleFinder(app.settings, im);
                        pArray{i} = p2;
                        numParticles(i) = length(p2);
                        app.combinedStats(i,1) = numParticles(i);
                        app.combinedStats(i,2) = mean(p2(:,5));
                        totalArea = p2(:,5);
                        app.combinedStats(i,3) = mean(p2(:,3)./totalArea);
                        app.combinedStats(i,4) = mean(p2(:,6));
                        if i ~= 1
                            pSize = [pSize; p2(:,5)];
                            % Average Intensity
                            totalArea = p2(:,5);
                            avgIntensity = [avgIntensity; p2(:,3)./totalArea];
                            % Peak Intensity
                            peakIntensity = [peakIntensity; p2(:,6)];
                        end
                        
                        if app.ImageSlider.Value ~= app.ImageSlider.Limits(2)
                            set(app.ImageSlider, 'Value', i+1);
                            displayImage(app, i+1, app.UIAxes);
                        end
                        
                        temp = app.cellArrayText{end};
                        if strcmp(temp(1:7), 'Percent')
                            app.cellArrayText{end} = sprintf('%s\n%s','Percent Completed: ' + string(round((i/app.ImageSlider.Limits(2)*100))) + '%');
                        else
                            app.cellArrayText{end+1} = sprintf('%s\n%s','Percent Completed: ' + string(round((i/app.ImageSlider.Limits(2)*100))) + '%');
                        end
                        app.StatusTextArea.Value = flip(app.cellArrayText);
                        drawnow;
                    end
                    
                    app.numParticlesOutput = numParticles;
                end
            end
        app.cumulativeParticleSize = pSize;
        app.cumulativeParticleIntensity = avgIntensity;
        app.cumulativePeakIntensity = peakIntensity;
        try
            app.sPlotArray = pArray;
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
            app.RunSingleImageStatisticsButton.Enable = true;
        catch ME
            
        end
        app.cellArrayText{end+1} = sprintf('%s\n%s', 'Complete');
        app.StatusTextArea.Value = flip(app.cellArrayText);
        drawnow;
        
        displayGroupStatistics(app);
        
        app.SaveGraphDataButton.Enable = true;
        app.NumberofParticlesCheckBox.Enable = true;
        app.CumulativeParticleSizeCheckBox.Enable = true;
        app.AverageParticleIntensityCheckBox_2.Enable = true;
        app.PeakParticleIntensityCheckBox_2.Enable = true;
        app.CombinedStatisticsCheckBox.Enable = true;
        end

        % Button pushed function: RunSingleImageStatisticsButton
        function RunSingleImageStatisticsButtonPushed(app, event)
            imageIntensity(app);
            averageParticleSize(app);
            particleIntensity(app);
            im = app.imArray{1,app.ImageSlider.Value};
            
            if app.Isimx
                axis(app.UIAxesProcImg, "equal");
                app.imagescimx(app.UIAxesProcImg, im.Frames{str2double(app.imxFrameSwitch.Value)});
            else
                axis(app.UIAxesProcImg, "equal");
                im = applyFilter(app,im);
                imagesc(app.UIAxesProcImg, im);
            end
            app.ParticleSizeHistogramCheckBox.Enable = true;
            app.AverageParticleIntensityCheckBox.Enable = true;
            app.PeakParticleIntensityCheckBox.Enable = true;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.RawImageButton
                app.invertColour = false;
            elseif selectedButton == app.InvertedImageButton
                app.invertColour = true;
            end
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end

        % Button pushed function: RefreshImageButton
        function RefreshImageButtonPushed(app, event)
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end

        % Value changed function: MarkerDropDown
        function MarkerDropDownValueChanged(app, event)
            value = app.MarkerDropDown.Value;
            app.marker = value;
        end

        % Value changed function: RadiusSpinner
        function RadiusSpinnerValueChanged(app, event)
            value = app.RadiusSpinner.Value;
            app.radius = value;
        end

        % Button pushed function: SaveGraphDataButton
        function SaveGraphDataButtonPushed(app, event)
            [filename, filepath] = uiputfile('*.mat', 'Save File');
            particleData = app.sPlotArray;
            numParticles = app.numParticlesOutput;
            particleSize = app.cumulativeParticleSize;
            aveIntensity = app.cumulativeParticleIntensity;
            peakIntensity = app.cumulativePeakIntensity;
            
            if filename
                save([filepath, '\', filename], 'particleData','numParticles','particleSize','aveIntensity','peakIntensity');
            end
        end

        % Button pushed function: ChooseOutputFolderButton_IndStat
        function ChooseOutputFolderButton_IndStatPushed(app, event)
            app.outputFilepath_IndStat = strcat(uigetdir('', 'Open Directory'), '\');
        end

        % Button pushed function: SaveStatisticsButton
        function SaveStatisticsButtonPushed(app, event)
            filepath = app.outputFilepath_IndStat;
            %% Individual Statistics
            % Particle Size Histogram
            if app.ParticleSizeHistogramCheckBox.Value
                filename = app.OutputFilenameEditField_ParticleSize.Value;
                fig = figure;
                set(fig, 'Name', filename);
                particleSizeGraph  = axes(fig);
                histogram(particleSizeGraph, app.averageParticleSizeOutput);
                xlabel('Particle Size (Pixels)');
                ylabel('Number of Particles');
                if app.figButton.Value
                    savefig(fig, strcat(filepath,filename));
                elseif app.pngButton.Value
                    saveas(fig, strcat(filepath,filename,'.png'));
                end
            end
            % Average Particle Intensity Histogram
            if app.AverageParticleIntensityCheckBox.Value
                filename = app.OutputFilenameEditField_AveParticleIntensity.Value;
                fig = figure;
                set(fig, 'Name', filename);
                particleIntensityGraph = axes(fig);
                histogram(particleIntensityGraph, app.particleIntensityOutput);
                xlabel('Particle Intensity');
                ylabel('Number of Particles');
                if app.figButton.Value
                    savefig(fig, strcat(filepath,filename));
                elseif app.pngButton.Value
                    saveas(fig, strcat(filepath,filename,'.png'));
                end
            end
            if app.PeakParticleIntensityCheckBox.Value
                filename = app.OutputFilenameEditField.Value;
                fig = figure;
                set(fig, 'Name', filename);
                peakIntensityGraph = axes(fig);
                histogram(peakIntensityGraph, app.peakIntensityOutput);
                xlabel('Particle Intensity');
                ylabel('Number of Particles');
                if app.figButton.Value
                    savefig(fig, strcat(filepath,filename));
                elseif app.pngButton.Value
                    saveas(fig, strcat(filepath,filename,'.png'));
                end
            end
            
            %% Group Statistics
            % Number of Particles
            if app.NumberofParticlesCheckBox.Value
                filename = app.OutputFilenameEditField_NumParticles.Value;
                fig = figure;
                set(fig, 'Name', filename);
                numParticlesGraph = axes(fig);
                plot(numParticlesGraph, app.numParticlesOutput);
                if app.Isimx
                    numParticlesGraph.XLim = [0.5, app.ImageSlider.Limits(2)*2 + 0.5];
                else
                    numParticlesGraph.XLim = [0.5, app.ImageSlider.Limits(2) + 0.5];
                end
                xlabel('Image');
                ylabel('Number of Particles');
                if app.figButton.Value
                    savefig(fig, strcat(filepath,filename));
                elseif app.pngButton.Value
                    saveas(fig, strcat(filepath,filename,'.png'));
                end
            end
            % Cumulative Particle Size
            if app.CumulativeParticleSizeCheckBox.Value
                filename = app.OutputFilenameEditField_CumPartSize.Value;
                fig = figure;
                set(fig, 'Name', filename);
                sizeParticlesGraph = axes(fig);
                histogram(sizeParticlesGraph, app.cumulativeParticleSize);
                xlabel('Particle Size (Pixels)');
                ylabel('Number of Particles');
                if app.figButton.Value
                    savefig(fig, strcat(filepath,filename));
                elseif app.pngButton.Value
                    saveas(fig, strcat(filepath,filename,'.png'));
                end
            end
            % Cumulative Particle Intensity
            if app.AverageParticleIntensityCheckBox_2.Value
                filename = app.OutputFilenameEditField_2.Value;
                fig = figure;
                set(fig, 'Name', filename);
                particleIntensityGraph = axes(fig);
                histogram(particleIntensityGraph, app.cumulativeParticleIntensity)
                xlabel('Particle Intensity');
                ylabel('Number of Particles');
                if app.figButton.Value
                    savefig(fig, strcat(filepath,filename));
                elseif app.pngButton.Value
                    saveas(fig, strcat(filepath,filename,'.png'));
                end
            end
            % Peak Particle Intensity
            if app.PeakParticleIntensityCheckBox_2.Value
                filename = app.OutputFilenameEditField_3.Value;
                fig = figure;
                set(fig, 'Name', filename);
                peakIntensityGraph = axes(fig);
                histogram(peakIntensityGraph, app.cumulativePeakIntensity);
                xlabel('Particle Intensity');
                ylabel('Number of Particles');
                if app.figButton.Value
                    savefig(fig, strcat(filepath,filename));
                elseif app.pngButton.Value
                    saveas(fig, strcat(filepath,filename,'.png'));
                end
            end
            % Combined Statistics
            if app.CombinedStatisticsCheckBox.Value
                filename = app.OutputFilenameEditField_4.Value;
                fig = figure;
                set(fig, 'Name', filename);
                combinedGraph = axes(fig);
                for i = 1:4
                    maxe(i) = max(app.combinedStats(:,i));
                    plotcombinedStats(:,i) = app.combinedStats(:,i)/max(app.combinedStats(:,i));
                end
                plot(combinedGraph, plotcombinedStats);
                combinedGraph.XLim = [0.5,length(plotcombinedStats(:,1))+0.5];
                legend(combinedGraph, ['Number of Particles /',num2str(maxe(1))], ['Average Particle Size /',num2str(maxe(2))], ...
                    ['Average Intensity /',num2str(maxe(3))], ['Average Peak Intensity /',num2str(maxe(4))]);
                xlabel('Image');
                ylabel('Value / Max');
                if app.figButton.Value
                    savefig(fig, strcat(filepath,filename));
                elseif app.pngButton.Value
                    saveas(fig, strcat(filepath,filename,'.png'));
                end
            end
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            [filename, filepath] = uiputfile('*.pdf','*.docx', 'Save File');
            filename = extractBefore(filename, '.');
            variables.numParticles = app.numParticlesOutput;
            variables.particleSize = app.cumulativeParticleSize;
            variables.aveIntensity = app.cumulativeParticleIntensity;
            variables.peakIntensity = app.cumulativePeakIntensity;
            variables.combinedStats = app.combinedStats;
            generateReport(filepath, filename, variables);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            set(app.ImageSlider, 'Value', 1);
            % saving all variables
            variables = {};
            ParticleData = app.sPlotArray;
            variables{1} = app.imName;
            variables{2} = app.imPath;
            variables{3} = app.outputFilepath_GroupStat;
            variables{4} = app.outputFilepath_IndStat;
            variables{5} = app.Isimx;
            variables{6} = app.invertColour;
            variables{7} = app.settings;
            variables{8} = app.marker;
            variables{9} = app.radius;
            variables{10} = app.particleIntensityOutput;
            variables{11} = app.averageParticleSizeOutput;
            variables{12} = app.cumulativeParticleSize;
            variables{13} = app.numParticlesOutput;
            variables{14} = app.outputFilepath_Report;
            variables{15} = app.peakIntensityOutput;
            variables{16} = app.cumulativeParticleIntensity;
            variables{17} = app.cumulativePeakIntensity;
            variables{18} = app.combinedStats;
            
            disp('finished variables');
            
            props = properties(app); % gets all of the components in the app
            values = cell(1, length(props));
            enable = cell(1, length(props));
            
            % saving components as values and enables
            for i = 1:length(props)
                disp(['component ', num2str(i)]);
                propName = props{i};
                property = app.(propName);
                if isprop(property, 'Value')
                    values{i} = app.(propName).Value;
                end
                if isprop(property, 'Enable')
                    enable{i} = app.(props{i}).Enable;
                end
            end
            
            disp('finished components');
            
            [file, path] = uiputfile('*.mat', 'Save File');
            if file
                save([path, '\', file], 'props', 'values', 'enable', 'variables', 'ParticleData');
            end
        end

        % Button pushed function: LoadSavedDataButton
        function LoadSavedDataButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Load File');
            if file
                load([path, '\', file], 'props', 'values', 'enable', 'variables', 'ParticleData');
            else
                return;
            end
            
            % loading all components
            for i = 1:length(props)
                propName = props{i};
                property = app.(propName);
                if isprop(property, 'Value')
                    app.(propName).Value = values{i};
                end
                if isprop(property, 'Enable')
                    app.(props{i}).Enable = enable{i};
                end
            end
            
            % loading all variables
            app.sPlotArray = ParticleData;
            app.imName = variables{1};
            app.imPath = variables{2};
            app.outputFilepath_GroupStat = variables{3};
            app.outputFilepath_IndStat = variables{4};
            app.Isimx = variables{5};
            app.invertColour = variables{6};
            app.settings = variables{7};
            app.marker = variables{8};
            app.radius = variables{9};
            app.particleIntensityOutput = variables{10};
            app.averageParticleSizeOutput = variables{11};
            app.cumulativeParticleSize = variables{12};
            app.numParticlesOutput = variables{13};
            app.outputFilepath_Report = variables{14};
            app.peakIntensityOutput = variables{15};
            app.cumulativeParticleIntensity = variables{16};
            app.cumulativePeakIntensity = variables{17};
            app.combinedStats = variables{18};
            
            %% setting up app to work with new data
            app.ParticleSizeHistogramCheckBox.Enable = 0;
            app.AverageParticleIntensityCheckBox.Enable = 0;
            cla(app.UIAxesProcImg);
            cla(app.UIAxesPartSize);
            cla(app.UIAxesPartInt);
            
            app.StatusTextArea.Value = {''};
            
            app.NumberofImagesEditFieldValueChanged(app);
            displayGroupStatistics(app);
            
            % load images into memory
            if (app.imName(2) ~= 0) % file selected
                % cleans out old data
                app.imArray = {};
                filepath = app.imPath;
                filename = app.imName;
                fullname = [filepath, filename];
                numDig = app.ImageIDdigitsEditField.Value;
                dotPos = strfind(filename, '.');
                extension = extractAfter(filename, dotPos);
                % imx extension
                if isequal(extension,'im7')
                    app.Isimx = true;
                    app.imxFrameSwitch.Enable = true;
                    if numDig > 0
                        initialNum = str2double(extractBetween(filename,dotPos-numDig,dotPos-1));
                        for i = 1:app.NumberofImagesEditField.Value
                            postfix = string(initialNum-1+i);
                            while strlength(postfix) < numDig
                                postfix = '0' + postfix;
                            end
                            filename = replaceBetween(filename, dotPos-numDig, dotPos-1, postfix);
                            fullname = [filepath, filename];
                            imx = readimx(fullname);
                            app.imArray{1,i} = imx;
                        end
                    else
                        imx = readimx(fullname);
                        app.imArray{1,1} = imx;
                    end
                %non imx extension
                else
                    if numDig > 0
                        initialNum = str2double(extractBetween(filename,dotPos-numDig,dotPos-1));
                        for i = 1:app.NumberofImagesEditField.Value
                            postfix = string(initialNum-1+i);
                            while strlength(postfix) < numDig
                                postfix = '0' + postfix;
                            end
                            filename = replaceBetween(filename, dotPos-numDig, dotPos-1, postfix);
                            fullname = [filepath, filename];
                            im = imread(fullname);
                            app.imArray{1,i} = im;
                        end
                    else
                        im = imread(fullname);
                        app.imArray{1,1} = im;
                    end
                end
            end
            
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end

        % Button pushed function: SaveImagesButton
        function SaveImagesButtonPushed(app, event)
            FilePath = strcat(uigetdir('', 'Open Directory'), '\');
            
            for i = 1:app.ImageSlider.Limits(2)
                if app.Isimx
                    % Frame 1
                    Frame = app.imArray{1,i}.Frames{1};
                    Components = Frame.Components;
                    Planes = Components{1}.Planes;
                    plane = Planes{1};
                    scales = Frame.Scales;
                    D = createPlane( plane, scales );
                    im = D.I';
                    im = applyFilter(app,im);
                    
                    if max(max(im)) > 255 && max(max(im)) < 4096
                        im = im/4095*255;
                    elseif max(max(im)) > 4096
                        im = im/65535*255;
                    end
                    im = uint8(im);
                    filename = [num2str(2*i-1),'.jpg'];
                    imwrite(im, [FilePath, filename], 'jpg');
                    
                    % Frame 2
                    Frame = app.imArray{1,i}.Frames{2};
                    Components = Frame.Components;
                    Planes = Components{1}.Planes;
                    plane = Planes{1};
                    scales = Frame.Scales;
                    D = createPlane( plane, scales );
                    im = D.I';
                    im = applyFilter(app,im);
                    
                    if max(max(im)) > 255 && max(max(im)) < 4096
                        im = im/4095*255;
                    elseif max(max(im)) > 4096
                        im = im/65535*255;
                    end
                    im = uint8(im);
                    filename = [num2str(2*i),'.jpg'];
                    imwrite(im, [FilePath, filename], 'jpg');
                else
                    im = app.imArray{1,i};
                    im = applyFilter(app,im);
                    
                    filename = [num2str(i),'.jpg'];
                    imwrite(im, [FilePath, filename], 'jpg');
                end
            end
        end

        % Button pushed function: SaveGifButton
        function SaveGifButtonPushed(app, event)
            FilePath = strcat(uigetdir('', 'Open Directory'), '\');
            Im_Name = 'images.gif';
            F_Name = [FilePath, Im_Name];
            
            for i = 1:app.ImageSlider.Limits(2)
                if app.Isimx
                    % Frame 1
                    Frame = app.imArray{1,i}.Frames{1};
                    Components = Frame.Components;
                    Planes = Components{1}.Planes;
                    plane = Planes{1};
                    scales = Frame.Scales;
                    D = createPlane( plane, scales );
                    im = D.I';
                    im = applyFilter(app,im);
                    
                    if max(max(im)) > 255 && max(max(im)) < 4096
                        im = im/4095*255;
                    elseif max(max(im)) > 4096
                        im = im/65535*255;
                    end
                    im = uint8(im);
                    
                    if i == 1
                        imwrite(im,F_Name,'gif','Loopcount',inf,'DelayTime', 0.1);
                    else
                        imwrite(im,F_Name,'gif','WriteMode','append','DelayTime', 0.1);
                    end
                    
                    % Frame 2
                    Frame = app.imArray{1,i}.Frames{2};
                    Components = Frame.Components;
                    Planes = Components{1}.Planes;
                    plane = Planes{1};
                    scales = Frame.Scales;
                    D = createPlane( plane, scales );
                    im = D.I';
                    im = applyFilter(app,im);
                    
                    if max(max(im)) > 255 && max(max(im)) < 4096
                        im = im/4095*255;
                    elseif max(max(im)) > 4096
                        im = im/65535*255;
                    end
                    im = uint8(im);
                    
                    imwrite(im,F_Name,'gif','WriteMode','append','DelayTime', 0.1);
                else
                    im = app.imArray{1,i};
                    im = applyFilter(app,im);
                    
                    if i == 1
                        imwrite(im,F_Name,'gif','Loopcount',inf,'DelayTime', 0.1);
                    else
                        imwrite(im,F_Name,'gif','WriteMode','append','DelayTime', 0.1);
                    end
                end
            end
        end

        % Button pushed function: GenerateAndApplyMaskButton
        function GenerateAndApplyMaskButtonPushed(app, event)
            if app.Isimx
                image = app.imArray{1,1}.Frames{1};
            else
                image = app.imArray{1,1};
            end
            app.cellArrayText{end+1} = sprintf('%s\n%s', 'Generating Mask');
            app.StatusTextArea.Value = flip(app.cellArrayText);
            drawnow;
            
            maskdone = 0;
            maskSettings=[20 15 31 11 5 200 0];
            while ~maskdone && maskSettings(3) > 0
                try
                    mask=pivTubeMask(image,maskSettings);%if mask fails, it hits the error in the masking function and jumps to the catch
                    maskdone=1;
                catch err
                    % mask failed, try different settings
                    maskSettings=maskSettings-[0 0 2 0 0 0 0];
                end
            end
            
            if maskSettings(3) < 0
                app.cellArrayText{end+1} = sprintf('%s\n%s', 'Mask Generation Failed');
                app.StatusTextArea.Value = flip(app.cellArrayText);
                drawnow;
            end
            
            imwrite(mask*255,'C:\Users\Kyle\Documents\coop 2021\Particle Detection Application\mask.bmp');
            mask = uint8(mask);
            app.Mask = mask;
            
            app.cellArrayText{end+1} = sprintf('%s\n%s', 'Complete');
            app.StatusTextArea.Value = flip(app.cellArrayText);
            drawnow;
            
            applyMask(app);
        end

        % Button pushed function: ApplyCustomMaskButton
        function ApplyCustomMaskButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp';'*.oct';'*.tiff';'*.tif';'*.im7'}, 'Select File to Open');
            fullname = [filepath, filename];
            mask = imread(fullname);
            app.Mask = mask / 255;
            applyMask(app);
        end

        % Button pushed function: ChooseIntensityThresholdButton
        function ChooseIntensityThresholdButtonPushed(app, event)
            img = app.imArray{app.ImageSlider.Value};
            if app.Isimx
                Frame = img.Frames{str2double(app.imxFrameSwitch.Value)};
                Components = Frame.Components;
                Planes = Components{1}.Planes;
                plane = Planes{1};
                scales = Frame.Scales;
                D = createPlane( plane, scales );
                img = D.I';
            end
            if app.invertColour
                img = invertImageColour(app, img);
            end
            figure(1)
            if max(max(img)) > 255 && max(max(img)) < 4096
                img = uint16(img);
                R = [0:4095];
                G = [0:4095];
                B = [0:4095];
                map = [R',G',B']/4095;
                imhist(img, map);
            elseif max(max(img)) > 4096
                img = uint16(img);
                imhist(img);
            else
                imhist(img);
            end
            
            try
                chApp = ChooseIntensityThreshold();
                waitfor(chApp.OKButton, 'UserData');
                output = chApp.output;
                chApp.delete;
                app.settings(5) = output;
                
                hold on;
                xline(app.settings(5), 'r');
                hold off;
            catch ME
                
            end
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end

        % Value changed function: FilterByThresholdCheckBox
        function FilterByThresholdCheckBoxValueChanged(app, event)
            value = app.FilterByThresholdCheckBox.Value;
            app.filterThresh = value;
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end

        % Value changed function: HighpassFilteringCheckBox
        function HighpassFilteringCheckBoxValueChanged(app, event)
            value = app.HighpassFilteringCheckBox.Value;
            app.filterGradient = value;
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end

        % Value changed function: ColourMapMaxEditField
        function ColourMapMaxEditFieldValueChanged(app, event)
            value = app.ColourMapMaxEditField.Value;
            R = cat(2, [0:value], ones([1, 255-value])*value);
            G = cat(2, [0:value], ones([1, 255-value])*value);
            B = cat(2, [0:value], ones([1, 255-value])*value);
            map = [R',G',B']/value;
            app.axesCMap = map;
            colormap(app.UIAxes, map);
            displayImage(app, app.ImageSlider.Value, app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParticleDetectionApplicationUIFigure = uifigure('Visible', 'off');
            app.ParticleDetectionApplicationUIFigure.Name = 'Particle Detection Application';
            app.TabGroup = uitabgroup(app.ParticleDetectionApplicationUIFigure);
            app.ParticleDetectionTab = uitab(app.TabGroup);
            app.ParticleDetectionTab.Title = 'Particle Detection';
            app.ImageSliderLabel = uilabel(app.ParticleDetectionTab);
            app.ImageSliderLabel.Text = 'Image';
            app.ImageSlider = uislider(app.ParticleDetectionTab);
            app.ImageSlider.Limits = [1 2];
            app.ImageSlider.MajorTicks = [1 2];
            app.ImageSlider.MajorTickLabels = {'1', '2'};
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSliderValueChanged, true);
            app.ImageSlider.MinorTicks = [];
            app.ImageSlider.Value = 1;
            app.imxFrameSwitchLabel = uilabel(app.ParticleDetectionTab);
            app.imxFrameSwitchLabel.Text = 'imx Frame';
            app.imxFrameSwitch = uiswitch(app.ParticleDetectionTab, 'slider');
            app.imxFrameSwitch.Items = {'1', '2'};
            app.imxFrameSwitch.ValueChangedFcn = createCallbackFcn(app, @imxFrameSwitchValueChanged, true);
            app.imxFrameSwitch.Enable = 'off';
            app.imxFrameSwitch.Value = '1';
            app.RefreshImageButton = uibutton(app.ParticleDetectionTab, 'push');
            app.RefreshImageButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshImageButtonPushed, true);
            app.RefreshImageButton.Text = 'Refresh Image';
            app.Panel = uipanel(app.ParticleDetectionTab);
            app.SelectImagesButton = uibutton(app.Panel, 'push');
            app.SelectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagesButtonPushed, true);
            app.SelectImagesButton.Text = 'Select Image(s)';
            app.MarkerOptionsPanel = uipanel(app.Panel);
            app.MarkerOptionsPanel.Title = 'Marker Options';
            app.RadiusSpinnerLabel = uilabel(app.MarkerOptionsPanel);
            app.RadiusSpinnerLabel.Text = 'Radius';
            app.RadiusSpinner = uispinner(app.MarkerOptionsPanel);
            app.RadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiusSpinnerValueChanged, true);
            app.RadiusSpinner.Value = 5;
            app.MarkerDropDownLabel = uilabel(app.MarkerOptionsPanel);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.MarkerOptionsPanel);
            app.MarkerDropDown.Items = {'o', '+', '*', '.', 'x'};
            app.MarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerDropDownValueChanged, true);
            app.MarkerDropDown.Value = 'o';
            app.ImageIDdigitsEditFieldLabel = uilabel(app.Panel);
            app.ImageIDdigitsEditFieldLabel.Text = 'Image ID digits:';
            app.ImageIDdigitsEditField = uieditfield(app.Panel, 'numeric');
            app.NumberofImagesEditFieldLabel = uilabel(app.Panel);
            app.NumberofImagesEditFieldLabel.Text = 'Number of Images';
            app.NumberofImagesEditField = uieditfield(app.Panel, 'numeric');
            app.NumberofImagesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofImagesEditFieldValueChanged, true);
            app.NumberofImagesEditField.Value = 1;
            app.StatusTextAreaLabel = uilabel(app.Panel);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.Panel);
            app.ParticleDetectionSettingsPanel = uipanel(app.Panel);
            app.ParticleDetectionSettingsPanel.Title = 'Particle Detection Settings';
            app.MinimumBrightnessSpinnerLabel = uilabel(app.ParticleDetectionSettingsPanel);
            app.MinimumBrightnessSpinnerLabel.Text = 'Minimum Brightness';
            app.MinimumBrightnessSpinner = uispinner(app.ParticleDetectionSettingsPanel);
            app.MinimumBrightnessSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimumBrightnessSpinnerValueChanged, true);
            app.MinimumBrightnessSpinner.Value = 20;
            app.ParticleWindowSizeSpinnerLabel = uilabel(app.ParticleDetectionSettingsPanel);
            app.ParticleWindowSizeSpinnerLabel.Text = 'Particle Window Size';
            app.ParticleWindowSizeSpinner = uispinner(app.ParticleDetectionSettingsPanel);
            app.ParticleWindowSizeSpinner.Step = 2;
            app.ParticleWindowSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @ParticleWindowSizeSpinnerValueChanged, true);
            app.ParticleWindowSizeSpinner.Value = 5;
            app.ImageProcessingPanel = uipanel(app.Panel);
            app.ImageProcessingPanel.Title = 'Image Processing';
            app.ButtonGroup = uibuttongroup(app.ImageProcessingPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.RawImageButton = uiradiobutton(app.ButtonGroup);
            app.RawImageButton.Text = 'Raw Image';
            app.RawImageButton.Value = true;
            app.InvertedImageButton = uiradiobutton(app.ButtonGroup);
            app.InvertedImageButton.Text = 'Inverted Image';
            app.GenerateAndApplyMaskButton = uibutton(app.ImageProcessingPanel, 'push');
            app.GenerateAndApplyMaskButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAndApplyMaskButtonPushed, true);
            app.GenerateAndApplyMaskButton.Text = 'Generate And Apply Mask';
            app.ApplyCustomMaskButton = uibutton(app.ImageProcessingPanel, 'push');
            app.ApplyCustomMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyCustomMaskButtonPushed, true);
            app.ApplyCustomMaskButton.Text = 'Apply Custom Mask';
            app.FilterByThresholdCheckBox = uicheckbox(app.ImageProcessingPanel);
            app.FilterByThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterByThresholdCheckBoxValueChanged, true);
            app.FilterByThresholdCheckBox.Text = 'Filter By Threshold';
            app.HighpassFilteringCheckBox = uicheckbox(app.ImageProcessingPanel);
            app.HighpassFilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighpassFilteringCheckBoxValueChanged, true);
            app.HighpassFilteringCheckBox.Text = 'Highpass Filtering';
            app.ChooseIntensityThresholdButton = uibutton(app.ImageProcessingPanel, 'push');
            app.ChooseIntensityThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseIntensityThresholdButtonPushed, true);
            app.ChooseIntensityThresholdButton.Enable = 'off';
            app.ChooseIntensityThresholdButton.Text = 'Choose Intensity Threshold';
            app.ColourMapMaxEditFieldLabel = uilabel(app.ImageProcessingPanel);
            app.ColourMapMaxEditFieldLabel.Text = 'Colour Map Max';
            app.ColourMapMaxEditField = uieditfield(app.ImageProcessingPanel, 'numeric');
            app.ColourMapMaxEditField.Limits = [0 255];
            app.ColourMapMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ColourMapMaxEditFieldValueChanged, true);
            app.ColourMapMaxEditField.Value = 255;
            app.Panel_4 = uipanel(app.Panel);
            app.ProcessSingleImageButton = uibutton(app.Panel_4, 'push');
            app.ProcessSingleImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessSingleImageButtonPushed, true);
            app.ProcessSingleImageButton.Enable = 'off';
            app.ProcessSingleImageButton.Text = 'Process Single Image';
            app.ShowParticlesCheckBox = uicheckbox(app.Panel_4);
            app.ShowParticlesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowParticlesCheckBoxValueChanged, true);
            app.ShowParticlesCheckBox.Text = 'Show Particles';
            app.ShowParticlesCheckBox.Value = true;
            app.ProcessAllImagesButton = uibutton(app.Panel_4, 'push');
            app.ProcessAllImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllImagesButtonPushed, true);
            app.ProcessAllImagesButton.Enable = 'off';
            app.ProcessAllImagesButton.Text = 'Process All Images';
            app.RunSingleImageStatisticsButton = uibutton(app.Panel_4, 'push');
            app.RunSingleImageStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @RunSingleImageStatisticsButtonPushed, true);
            app.RunSingleImageStatisticsButton.Enable = 'off';
            app.RunSingleImageStatisticsButton.Text = 'Run Single Image Statistics';
            app.UIAxes = uiaxes(app.ParticleDetectionTab);
            zlabel(app.UIAxes, 'Z')
            app.SingleImageStatisticsTab = uitab(app.TabGroup);
            app.SingleImageStatisticsTab.Title = 'Single Image Statistics';
            app.TabGroup2 = uitabgroup(app.SingleImageStatisticsTab);
            app.ParticleSizeTab = uitab(app.TabGroup2);
            app.ParticleSizeTab.Title = 'Particle Size';
            app.UIAxesPartSize = uiaxes(app.ParticleSizeTab);
            zlabel(app.UIAxesPartSize, 'Z')
            app.AverageParticleIntensityTab = uitab(app.TabGroup2);
            app.AverageParticleIntensityTab.Title = 'Average Particle Intensity';
            app.UIAxesPartInt = uiaxes(app.AverageParticleIntensityTab);
            zlabel(app.UIAxesPartInt, 'Z')
            app.PeakParticleIntensityTab = uitab(app.TabGroup2);
            app.PeakParticleIntensityTab.Title = 'Peak Particle Intensity';
            app.UIAxesPartInt_2 = uiaxes(app.PeakParticleIntensityTab);
            zlabel(app.UIAxesPartInt_2, 'Z')
            app.UIAxesProcImg = uiaxes(app.SingleImageStatisticsTab);
            title(app.UIAxesProcImg, 'Current Image')
            zlabel(app.UIAxesProcImg, 'Z')
            app.GroupStatisticsTab = uitab(app.TabGroup);
            app.GroupStatisticsTab.Title = 'Group Statistics';
            app.TabGroup3 = uitabgroup(app.GroupStatisticsTab);
            app.NumberofParticlesPerImageTab = uitab(app.TabGroup3);
            app.NumberofParticlesPerImageTab.Title = 'Number of Particles Per Image';
            app.UIAxesNumPart = uiaxes(app.NumberofParticlesPerImageTab);
            xlabel(app.UIAxesNumPart, 'Image')
            ylabel(app.UIAxesNumPart, 'Number of Particles')
            zlabel(app.UIAxesNumPart, 'Z')
            app.CumulativeParticleSizeTab = uitab(app.TabGroup3);
            app.CumulativeParticleSizeTab.Title = 'Cumulative Particle Size';
            app.UIAxes2 = uiaxes(app.CumulativeParticleSizeTab);
            xlabel(app.UIAxes2, 'Particle Size (Pixels)')
            ylabel(app.UIAxes2, 'Number of Particles')
            zlabel(app.UIAxes2, 'Z')
            app.AverageParticleIntensityTab_2 = uitab(app.TabGroup3);
            app.AverageParticleIntensityTab_2.Title = 'Average Particle Intensity';
            app.UIAxes3 = uiaxes(app.AverageParticleIntensityTab_2);
            xlabel(app.UIAxes3, 'Brightness')
            ylabel(app.UIAxes3, 'Number of Particles')
            zlabel(app.UIAxes3, 'Z')
            app.PeakParticleIntensityTab_2 = uitab(app.TabGroup3);
            app.PeakParticleIntensityTab_2.Title = 'Peak Particle Intensity';
            app.UIAxes4 = uiaxes(app.PeakParticleIntensityTab_2);
            xlabel(app.UIAxes4, 'Brightness')
            ylabel(app.UIAxes4, 'Number of Particles')
            zlabel(app.UIAxes4, 'Z')
            app.CombinedStatisticsTab = uitab(app.TabGroup3);
            app.CombinedStatisticsTab.Title = 'Combined Statistics';
            app.UIAxes4_2 = uiaxes(app.CombinedStatisticsTab);
            xlabel(app.UIAxes4_2, 'Image')
            ylabel(app.UIAxes4_2, 'Ratio to Max')
            zlabel(app.UIAxes4_2, 'Z')
            app.UIAxes4_2.YLim = [0 1.5];
            app.LoadSaveTab = uitab(app.TabGroup);
            app.LoadSaveTab.Title = 'Load/Save';
            app.SaveDataPanel = uipanel(app.LoadSaveTab);
            app.SaveDataPanel.Title = 'Save Data';
            app.SaveGraphDataButton = uibutton(app.SaveDataPanel, 'push');
            app.SaveGraphDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphDataButtonPushed, true);
            app.SaveGraphDataButton.Enable = 'off';
            app.SaveGraphDataButton.Text = 'Save Graph Data';
            app.GenerateReportButton = uibutton(app.SaveDataPanel, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.LoadSavedDataButton = uibutton(app.SaveDataPanel, 'push');
            app.LoadSavedDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSavedDataButtonPushed, true);
            app.LoadSavedDataButton.Text = 'Load Saved Data';
            app.SaveDataButton = uibutton(app.SaveDataPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.SaveIndividualStatisticsPanel = uipanel(app.LoadSaveTab);
            app.SaveIndividualStatisticsPanel.Title = 'Save Individual Statistics';
            app.SingleImageStatisticsPanel = uipanel(app.SaveIndividualStatisticsPanel);
            app.SingleImageStatisticsPanel.Title = 'Single Image Statistics';
            app.ParticleSizeHistogramCheckBox = uicheckbox(app.SingleImageStatisticsPanel);
            app.ParticleSizeHistogramCheckBox.Enable = 'off';
            app.ParticleSizeHistogramCheckBox.Text = 'Particle Size Histogram';
            app.OutputFilenameEditField_3Label = uilabel(app.SingleImageStatisticsPanel);
            app.OutputFilenameEditField_3Label.Text = 'Output Filename';
            app.OutputFilenameEditField_ParticleSize = uieditfield(app.SingleImageStatisticsPanel, 'text');
            app.OutputFilenameEditField_ParticleSize.Value = 'PartSize';
            app.AverageParticleIntensityCheckBox = uicheckbox(app.SingleImageStatisticsPanel);
            app.AverageParticleIntensityCheckBox.Enable = 'off';
            app.AverageParticleIntensityCheckBox.Text = 'Average Particle Intensity';
            app.OutputFilenameEditField_4Label = uilabel(app.SingleImageStatisticsPanel);
            app.OutputFilenameEditField_4Label.Text = 'Output Filename';
            app.OutputFilenameEditField_AveParticleIntensity = uieditfield(app.SingleImageStatisticsPanel, 'text');
            app.OutputFilenameEditField_AveParticleIntensity.Value = 'AvePartInt';
            app.PeakParticleIntensityCheckBox = uicheckbox(app.SingleImageStatisticsPanel);
            app.PeakParticleIntensityCheckBox.Enable = 'off';
            app.PeakParticleIntensityCheckBox.Text = 'Peak Particle Intensity';
            app.OutputFilenameEditFieldLabel = uilabel(app.SingleImageStatisticsPanel);
            app.OutputFilenameEditFieldLabel.Text = 'Output Filename';
            app.OutputFilenameEditField = uieditfield(app.SingleImageStatisticsPanel, 'text');
            app.OutputFilenameEditField.Value = 'PeakPartInt';
            app.GroupStatisticsPanel = uipanel(app.SaveIndividualStatisticsPanel);
            app.GroupStatisticsPanel.Title = 'Group Statistics';
            app.NumberofParticlesCheckBox = uicheckbox(app.GroupStatisticsPanel);
            app.NumberofParticlesCheckBox.Enable = 'off';
            app.NumberofParticlesCheckBox.Text = 'Number of Particles';
            app.CumulativeParticleSizeCheckBox = uicheckbox(app.GroupStatisticsPanel);
            app.CumulativeParticleSizeCheckBox.Enable = 'off';
            app.CumulativeParticleSizeCheckBox.Text = 'Cumulative Particle Size';
            app.OutputFilenameEditField_5Label = uilabel(app.GroupStatisticsPanel);
            app.OutputFilenameEditField_5Label.Text = 'Output Filename';
            app.OutputFilenameEditField_NumParticles = uieditfield(app.GroupStatisticsPanel, 'text');
            app.OutputFilenameEditField_NumParticles.Value = 'NumPart';
            app.OutputFilenameEditField_6Label = uilabel(app.GroupStatisticsPanel);
            app.OutputFilenameEditField_6Label.Text = 'Output Filename';
            app.OutputFilenameEditField_CumPartSize = uieditfield(app.GroupStatisticsPanel, 'text');
            app.OutputFilenameEditField_CumPartSize.Value = 'TotalPartSize';
            app.AverageParticleIntensityCheckBox_2 = uicheckbox(app.GroupStatisticsPanel);
            app.AverageParticleIntensityCheckBox_2.Enable = 'off';
            app.AverageParticleIntensityCheckBox_2.Text = 'Average Particle Intensity';
            app.PeakParticleIntensityCheckBox_2 = uicheckbox(app.GroupStatisticsPanel);
            app.PeakParticleIntensityCheckBox_2.Enable = 'off';
            app.PeakParticleIntensityCheckBox_2.Text = 'Peak Particle Intensity';
            app.CombinedStatisticsCheckBox = uicheckbox(app.GroupStatisticsPanel);
            app.CombinedStatisticsCheckBox.Enable = 'off';
            app.CombinedStatisticsCheckBox.Text = 'Combined Statistics';
            app.OutputFilenameEditField_2Label = uilabel(app.GroupStatisticsPanel);
            app.OutputFilenameEditField_2Label.Text = 'Output Filename';
            app.OutputFilenameEditField_2 = uieditfield(app.GroupStatisticsPanel, 'text');
            app.OutputFilenameEditField_2.Value = 'AvePartInt_Total';
            app.OutputFilenameEditField_3Label_2 = uilabel(app.GroupStatisticsPanel);
            app.OutputFilenameEditField_3Label_2.Text = 'Output Filename';
            app.OutputFilenameEditField_3 = uieditfield(app.GroupStatisticsPanel, 'text');
            app.OutputFilenameEditField_3.Value = 'PeakPartInt_Total';
            app.OutputFilenameEditField_4Label_2 = uilabel(app.GroupStatisticsPanel);
            app.OutputFilenameEditField_4Label_2.Text = 'Output Filename';
            app.OutputFilenameEditField_4 = uieditfield(app.GroupStatisticsPanel, 'text');
            app.OutputFilenameEditField_4.Value = 'CombinedGroupStat';
            app.ChooseOutputFolderButton_IndStat = uibutton(app.SaveIndividualStatisticsPanel, 'push');
            app.ChooseOutputFolderButton_IndStat.ButtonPushedFcn = createCallbackFcn(app, @ChooseOutputFolderButton_IndStatPushed, true);
            app.ChooseOutputFolderButton_IndStat.Text = 'Choose Output Folder';
            app.SaveStatisticsButton = uibutton(app.SaveIndividualStatisticsPanel, 'push');
            app.SaveStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStatisticsButtonPushed, true);
            app.SaveStatisticsButton.Text = 'Save Statistics';
            app.ButtonGroup_2 = uibuttongroup(app.SaveIndividualStatisticsPanel);
            app.figButton = uiradiobutton(app.ButtonGroup_2);
            app.figButton.Text = '.fig';
            app.figButton.Value = true;
            app.pngButton = uiradiobutton(app.ButtonGroup_2);
            app.pngButton.Text = '.png';
            app.SaveImagesPanel = uipanel(app.LoadSaveTab);
            app.SaveImagesPanel.Title = 'Save Images';
            app.SaveImagesButton = uibutton(app.SaveImagesPanel, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagesButtonPushed, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.SaveGifButton = uibutton(app.SaveImagesPanel, 'push');
            app.SaveGifButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGifButtonPushed, true);
            app.SaveGifButton.Text = 'Save Gif';
            % Show the figure after all components are created
            app.ParticleDetectionApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParticleDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParticleDetectionApplicationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParticleDetectionApplicationUIFigure)
        end
    end
end